classdef ex2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PrincipleAmountEditFieldLabel  matlab.ui.control.Label
        PrincipleAmountEditField       matlab.ui.control.NumericEditField
        DurationEditFieldLabel         matlab.ui.control.Label
        DurationEditField              matlab.ui.control.NumericEditField
        RateEditFieldLabel             matlab.ui.control.Label
        RateEditField                  matlab.ui.control.NumericEditField
        simpleinterestButton           matlab.ui.control.Button
        CompoundinterestButton         matlab.ui.control.Button
        interestEditFieldLabel         matlab.ui.control.Label
        interestEditField              matlab.ui.control.NumericEditField
        CompoundintrestEditFieldLabel  matlab.ui.control.Label
        CompoundintrestEditField       matlab.ui.control.NumericEditField
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: simpleinterestButton
        function simpleinterestButtonPushed(app, event)
            p=app.PrincipleAmountEditField.Value;
            r=app.RateEditField.Value;
            t=app.DurationEditField.Value;
            app.interestEditField.Value=p*t*r/100;
        end

        % Button pushed function: CompoundinterestButton
        function CompoundinterestButtonPushed(app, event)
            p=app.PrincipleAmountEditField.Value;
            r=app.RateEditField.Value;
            t=app.DurationEditField.Value;
            app.CompoundintrestEditField.Value=p*((1+r/100)^t)-p;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PrincipleAmountEditFieldLabel = uilabel(app.UIFigure);
            app.PrincipleAmountEditFieldLabel.Text = 'Principle Amount';
            app.PrincipleAmountEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationEditFieldLabel = uilabel(app.UIFigure);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateEditFieldLabel = uilabel(app.UIFigure);
            app.RateEditFieldLabel.Text = {'Rate'; ''};
            app.RateEditField = uieditfield(app.UIFigure, 'numeric');
            app.simpleinterestButton = uibutton(app.UIFigure, 'push');
            app.simpleinterestButton.ButtonPushedFcn = createCallbackFcn(app, @simpleinterestButtonPushed, true);
            app.simpleinterestButton.Text = {'simple interest'; ''};
            app.CompoundinterestButton = uibutton(app.UIFigure, 'push');
            app.CompoundinterestButton.ButtonPushedFcn = createCallbackFcn(app, @CompoundinterestButtonPushed, true);
            app.CompoundinterestButton.Text = 'Compound interest';
            app.interestEditFieldLabel = uilabel(app.UIFigure);
            app.interestEditFieldLabel.Text = {'interest'; ''};
            app.interestEditField = uieditfield(app.UIFigure, 'numeric');
            app.interestEditField.ValueDisplayFormat = '%.0f';
            app.CompoundintrestEditFieldLabel = uilabel(app.UIFigure);
            app.CompoundintrestEditFieldLabel.Text = 'Compound intrest';
            app.CompoundintrestEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ex2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end